classdef apppro_m < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        selectimageDropDown            matlab.ui.control.DropDown
        selectimageLabel               matlab.ui.control.Label
        fingersDetectedEditField       matlab.ui.control.NumericEditField
        fingersDetectedEditFieldLabel  matlab.ui.control.Label
        DetectButton                   matlab.ui.control.Button
        UIAxes2                        matlab.ui.control.UIAxes
        UIAxes                         matlab.ui.control.UIAxes
    end


    %   properties (Access = public)
    %     ImageDropDown             matlab.ui.control.DropDown
    % end
    properties (Access = private)

       InputImage=[]%  Description
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function GestureDetectionButtonPushed(app, event)
            % Button pushed function: GestureButton


        end

        % Button pushed function: DetectButton
        function DetectButtonPushed(app, event)
         selectedImage = app.selectimageDropDown.Value;
    if isempty(selectedImage)
        uialert(app.UIFigure, 'Please select an image first.', 'Warning');
        return;
    end

    
    if strcmp(selectedImage, 'Img5')
        app.InputImage = imread('Ps1.jpg'); 
    elseif strcmp(selectedImage, 'Img2')
        app.InputImage = imread('Ps4.jpg');
    elseif strcmp(selectedImage, 'Img1')
        app.InputImage = imread('Thumb3.jpg'); 
   elseif strcmp(selectedImage, 'Img4')
        app.InputImage = imread('Ps3.jpg');
elseif strcmp(selectedImage, 'Img6')
        app.InputImage = imread('1.jpg');
elseif strcmp(selectedImage, 'Img3')
        app.InputImage = imread('Ten.jpg');
    else

    uialert(app.UIFigure, 'Invalid image selection.', 'Error');        
return;
    end

  
    grayImage = rgb2gray(app.InputImage);

  
    blurredImage = imgaussfilt(grayImage, 2); % Adjust the standard deviation as needed

    thresholdValue = 0.5; 
    binaryImage = imbinarize(blurredImage, thresholdValue);

    
    filledImage = imfill(binaryImage, 'holes');

    % Remove small objects (noise) from the binary image
    minObjectSize = 100; % Adjust the minimum object size as needed
    cleanedImage = bwareaopen(filledImage, minObjectSize);

    % Find connected components (blobs) in the binary image
    blobAnalysis = regionprops(cleanedImage, 'BoundingBox', 'Area', 'Centroid');
    numFingers = 0;

    
    handCenterX = size(app.InputImage, 2) / 2; % Set the hand center X coordinate (assumed to be in the middle of the image)
    handCenterY = size(app.InputImage, 1) / 2; % Set the hand center Y coordinate (assumed to be in the middle of the image)
    minFingerArea = 200; % Adjust the minimum finger area as needed

    for i = 1:numel(blobAnalysis)
        area = blobAnalysis(i).Area;
        centroid = blobAnalysis(i).Centroid;

        if area > minFingerArea && centroid(2) < handCenterY && centroid(1) > handCenterX
            numFingers = numFingers + 1;
        end
    end

    
    app.fingersDetectedEditField.Value = numFingers;

  
    cla(app.UIAxes); % Clear previous contents of the axes
    imshow(app.InputImage, 'Parent', app.UIAxes); 
    title(app.UIAxes, 'Original Image');

    cla(app.UIAxes2); % Clear previous contents of the axes
    image(cleanedImage, 'Parent', app.UIAxes2); 
    colormap(app.UIAxes2, [0, 0, 0; 1, 1, 1]); % Set colormap for binary image (0=black, 1=white)
    title(app.UIAxes2, 'Cleaned Binary Image');


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Title')
            zlabel(app.UIAxes2, 'Z')
            app.DetectButton = uibutton(app.UIFigure, 'push');
            app.DetectButton.ButtonPushedFcn = createCallbackFcn(app, @DetectButtonPushed, true);
            app.DetectButton.Text = 'Detect';
            app.fingersDetectedEditFieldLabel = uilabel(app.UIFigure);
            app.fingersDetectedEditFieldLabel.Text = 'fingersDetected';
            app.fingersDetectedEditField = uieditfield(app.UIFigure, 'numeric');
            app.selectimageLabel = uilabel(app.UIFigure);
            app.selectimageLabel.Text = ' selectimage';
            app.selectimageDropDown = uidropdown(app.UIFigure);
            app.selectimageDropDown.Items = {'Img1', 'Img2', 'Img3', 'Img4', 'Img5', 'Img6'};
            app.selectimageDropDown.Value = 'Img4';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = apppro_m

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end